classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure       matlab.ui.Figure
        set3_3                     matlab.ui.control.NumericEditField
        set3_1                     matlab.ui.control.NumericEditField
        set3_2                     matlab.ui.control.NumericEditField
        Goto3Button                matlab.ui.control.Button
        Set3Button                 matlab.ui.control.Button
        set2_3                     matlab.ui.control.NumericEditField
        set2_1                     matlab.ui.control.NumericEditField
        set2_2                     matlab.ui.control.NumericEditField
        Goto2Button                matlab.ui.control.Button
        Set2Button                 matlab.ui.control.Button
        set1_3                     matlab.ui.control.NumericEditField
        set1_1                     matlab.ui.control.NumericEditField
        set1_2                     matlab.ui.control.NumericEditField
        Goto1Button                matlab.ui.control.Button
        Set1Button                 matlab.ui.control.Button
        HOMEButton                 matlab.ui.control.Button
        GOButton                   matlab.ui.control.Button
        Joint3ValueEditField       matlab.ui.control.NumericEditField
        Joint3ValueLabel           matlab.ui.control.Label
        Joint2ValueEditField       matlab.ui.control.NumericEditField
        Joint2ValueLabel           matlab.ui.control.Label
        Joint1ValueEditField       matlab.ui.control.NumericEditField
        Joint1ValueEditFieldLabel  matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint1SliderLabel          matlab.ui.control.Label
        axes_arm                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0];
        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));
            
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            %app.axes_arm.XLim = [-160 250];
            %app.axes_arm.YLim = [-250 250];
            %app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint1ValueEditField.Value = app.jointAngles(1);
            app.updateArm()
            
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint2ValueEditField.Value = app.jointAngles(2);
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint3ValueEditField.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1ValueEditField.Value;
            app.jointAngles(2) = app.Joint2ValueEditField.Value;
            app.jointAngles(3) = app.Joint3ValueEditField.Value;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm

        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;

            app.updateArm()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1(1) = app.Joint1Slider.Value;
            app.savedAngles1(2) = app.Joint2Slider.Value;
            app.savedAngles1(3) = app.Joint3Slider.Value;

            app.set1_1.Value = app.savedAngles1(1);
            app.set1_2.Value = app.savedAngles1(2);
            app.set1_3.Value = app.savedAngles1(3);
            app.updateArm()
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles1(1);
            app.jointAngles(2) = app.savedAngles1(2);
            app.jointAngles(3) = app.savedAngles1(3);

            app.updateArm()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2(1) = app.Joint1Slider.Value;
            app.savedAngles2(2) = app.Joint2Slider.Value;
            app.savedAngles2(3) = app.Joint3Slider.Value;

            app.set2_1.Value = app.savedAngles2(1);
            app.set2_2.Value = app.savedAngles2(2);
            app.set2_3.Value = app.savedAngles2(3);
            app.updateArm()
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles2(1);
            app.jointAngles(2) = app.savedAngles2(2);
            app.jointAngles(3) = app.savedAngles2(3);

            app.updateArm()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3(1) = app.Joint1Slider.Value;
            app.savedAngles3(2) = app.Joint2Slider.Value;
            app.savedAngles3(3) = app.Joint3Slider.Value;

            app.set3_1.Value = app.savedAngles3(1);
            app.set3_2.Value = app.savedAngles3(2);
            app.set3_3.Value = app.savedAngles3(3);
            app.updateArm()
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles3(1);
            app.jointAngles(2) = app.savedAngles3(2);
            app.jointAngles(3) = app.savedAngles3(3);

            app.updateArm()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1ValueEditFieldLabel.Text = 'Joint1 Value';
            app.Joint1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint2ValueLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2ValueLabel.Text = 'Joint2 Value';
            app.Joint2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint3ValueLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3ValueLabel.Text = 'Joint3 Value';
            app.Joint3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.GOButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.Set1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.set1_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Goto2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.set2_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.Goto3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.set3_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end