classdef Exam2BYOB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure            matlab.ui.Figure
        PlayAllAll5SetsButton           matlab.ui.control.Button
        SpecialMoveChopVegetableButton  matlab.ui.control.Button
        set3_5                          matlab.ui.control.NumericEditField
        set3_4                          matlab.ui.control.NumericEditField
        set4_5                          matlab.ui.control.NumericEditField
        set4_4                          matlab.ui.control.NumericEditField
        set5_5                          matlab.ui.control.NumericEditField
        set5_4                          matlab.ui.control.NumericEditField
        set2_5                          matlab.ui.control.NumericEditField
        set2_4                          matlab.ui.control.NumericEditField
        set1_5                          matlab.ui.control.NumericEditField
        set1_4                          matlab.ui.control.NumericEditField
        set5_3                          matlab.ui.control.NumericEditField
        set5_1                          matlab.ui.control.NumericEditField
        set5_2                          matlab.ui.control.NumericEditField
        Goto5Button                     matlab.ui.control.Button
        Set5Button                      matlab.ui.control.Button
        set4_3                          matlab.ui.control.NumericEditField
        set4_1                          matlab.ui.control.NumericEditField
        set4_2                          matlab.ui.control.NumericEditField
        Goto4Button                     matlab.ui.control.Button
        Set4Button                      matlab.ui.control.Button
        Joint5ValueEditField            matlab.ui.control.NumericEditField
        Joint5ValueEditFieldLabel       matlab.ui.control.Label
        Joint4ValueEditField            matlab.ui.control.NumericEditField
        Joint4ValueEditFieldLabel       matlab.ui.control.Label
        Joint5Slider                    matlab.ui.control.Slider
        Joint5SliderLabel               matlab.ui.control.Label
        Joint4Slider                    matlab.ui.control.Slider
        Joint4Label                     matlab.ui.control.Label
        set3_3                          matlab.ui.control.NumericEditField
        set3_1                          matlab.ui.control.NumericEditField
        set3_2                          matlab.ui.control.NumericEditField
        Goto3Button                     matlab.ui.control.Button
        Set3Button                      matlab.ui.control.Button
        set2_3                          matlab.ui.control.NumericEditField
        set2_1                          matlab.ui.control.NumericEditField
        set2_2                          matlab.ui.control.NumericEditField
        Goto2Button                     matlab.ui.control.Button
        Set2Button                      matlab.ui.control.Button
        set1_3                          matlab.ui.control.NumericEditField
        set1_1                          matlab.ui.control.NumericEditField
        set1_2                          matlab.ui.control.NumericEditField
        Goto1Button                     matlab.ui.control.Button
        Set1Button                      matlab.ui.control.Button
        HOMEButton                      matlab.ui.control.Button
        GOButton                        matlab.ui.control.Button
        Joint3ValueEditField            matlab.ui.control.NumericEditField
        Joint3ValueLabel                matlab.ui.control.Label
        Joint2ValueEditField            matlab.ui.control.NumericEditField
        Joint2ValueLabel                matlab.ui.control.Label
        Joint1ValueEditField            matlab.ui.control.NumericEditField
        Joint1ValueEditFieldLabel       matlab.ui.control.Label
        Joint3Slider                    matlab.ui.control.Slider
        Joint3Label                     matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2Label                     matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1Label                     matlab.ui.control.Label
        axes_arm                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices
        jointAngles = [0 0 0 0 0 0];
        savedAngles1 = [0 0 0 0 0 0];
        savedAngles2 = [0 0 0 0 0 0];
        savedAngles3 = [0 0 0 0 0 0];
        savedAngles4 = [0 0 0 0 0 0];
        savedAngles5 = [0 0 0 0 0 0];

    end
    
    methods (Access = private)
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 24, 90, app.jointAngles(1));
            A2 = create_A_matrix(50, 0, 0, app.jointAngles(2));
            A3 = create_A_matrix(50, 0, -90, app.jointAngles(3));
            A4 = create_A_matrix(0, -19, -90, app.jointAngles(4)-90);
            A5 = create_A_matrix(0, 17, 0, app.jointAngles(5));

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            T0_4 = A1 * A2 * A3 * A4;
            T0_5 = A1 * A2 * A3 * A4 * A5;
            
            
            % TODO: Use the current T matricies to transform the original patch vertices
            link2VerticesWRTground = T0_1 * app.link2Vertices;
            link3VerticesWRTground = T0_2 * app.link3Vertices;
            link4VerticesWRTground = T0_3 * app.link4Vertices;
            link5VerticesWRTground = T0_4 * app.link5Vertices;
            link6VerticesWRTground = T0_5 * app.link6Vertices;
            
            % TODO: Update the patches with the newly transformed vertices
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');
        end
        
        function animation(app, selectedAngles)
            startAngles = app.jointAngles;
            nSteps = 20;
            for k = 1:nSteps
                s = k / nSteps;
                app.jointAngles = startAngles + s * (selectedAngles - startAngles);
                app.updateArm();
                pause(0.01);
         
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            % Prepare the axes_arm properties
            %app.axes_arm.View = [230 50];
            app.axes_arm.View = [45 36];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("Part Studio 1 Copy 1 - Part 1.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link2Patch = makeStlLink("Part Studio 1 Copy 1 - Part 2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("Part Studio 1 Copy 1 - Part 3.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            
            app.link4Patch = makeStlLink("Part Studio 1 Copy 1 - Part 4.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
            
            app.link5Patch = makeStlLink("Part Studio 1 Copy 1 - Part 5.stl", app.axes_arm, [190/255 100/255 170/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));
            
            app.link6Patch = makeStlLink("Part Studio 1 Copy 1 - Part 6.stl", app.axes_arm, [0/255 0/255 255/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));
            
            % End: Code that can go into your GUI's opening function.
            
            updateArm(app);
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint1ValueEditField.Value = app.jointAngles(1);
            app.updateArm()
            
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint2ValueEditField.Value = app.jointAngles(2);
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint3ValueEditField.Value = app.jointAngles(3);
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            go = app.jointAngles();
            go(1) = app.Joint1ValueEditField.Value;
            go(2)  = app.Joint2ValueEditField.Value;
            go(3) = app.Joint3ValueEditField.Value;
            go(4)  = app.Joint4ValueEditField.Value;
            go(5)  = app.Joint5ValueEditField.Value;
            app.animation(go);
            
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
            app.updateArm

        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.animation(zeros(1,6));
            %app.jointAngles(1) = 0;
            %app.jointAngles(2) = 0;
            %app.jointAngles(3) = 0;
            %app.jointAngles(5) = 0;
            %app.jointAngles(4) = 0;
            %app.updateArm()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1(1) = app.Joint1Slider.Value;
            app.savedAngles1(2) = app.Joint2Slider.Value;
            app.savedAngles1(3) = app.Joint3Slider.Value;
            app.savedAngles1(4) = app.Joint4Slider.Value;
            app.savedAngles1(5) = app.Joint5Slider.Value;

            app.set1_1.Value = app.savedAngles1(1);
            app.set1_2.Value = app.savedAngles1(2);
            app.set1_3.Value = app.savedAngles1(3);
            app.set1_4.Value = app.savedAngles1(4);
            app.set1_5.Value = app.savedAngles1(5);
            app.updateArm()
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.animation(app.savedAngles1)
            %app.jointAngles(1) = app.savedAngles1(1);
            %app.jointAngles(2) = app.savedAngles1(2);
            %app.jointAngles(3) = app.savedAngles1(3);
            %app.jointAngles(4) = app.savedAngles1(4);
            %app.jointAngles(5) = app.savedAngles1(5);
            %app.updateArm()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2(1) = app.Joint1Slider.Value;
            app.savedAngles2(2) = app.Joint2Slider.Value;
            app.savedAngles2(3) = app.Joint3Slider.Value;
            app.savedAngles2(4) = app.Joint4Slider.Value;
            app.savedAngles2(5) = app.Joint5Slider.Value;

            app.set2_1.Value = app.savedAngles2(1);
            app.set2_2.Value = app.savedAngles2(2);
            app.set2_3.Value = app.savedAngles2(3);
            app.set2_4.Value = app.savedAngles2(4);
            app.set2_5.Value = app.savedAngles2(5);
            app.updateArm()
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.animation(app.savedAngles2)

            %app.jointAngles(1) = app.savedAngles2(1);
            %app.jointAngles(2) = app.savedAngles2(2);
            %app.jointAngles(3) = app.savedAngles2(3);
            %app.jointAngles(4) = app.savedAngles2(4);
            %app.jointAngles(5) = app.savedAngles2(5);
            %app.updateArm()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3(1) = app.Joint1Slider.Value;
            app.savedAngles3(2) = app.Joint2Slider.Value;
            app.savedAngles3(3) = app.Joint3Slider.Value;
            app.savedAngles3(4) = app.Joint4Slider.Value;
            app.savedAngles3(5) = app.Joint5Slider.Value;

            app.set3_1.Value = app.savedAngles3(1);
            app.set3_2.Value = app.savedAngles3(2);
            app.set3_3.Value = app.savedAngles3(3);
            app.set3_4.Value = app.savedAngles3(4);
            app.set3_5.Value = app.savedAngles3(5);
            app.updateArm()
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.animation(app.savedAngles3)
           
            %app.jointAngles(1) = app.savedAngles3(1);
            %app.jointAngles(2) = app.savedAngles3(2);
            %app.jointAngles(3) = app.savedAngles3(3);
            %app.jointAngles(4) = app.savedAngles3(4);
            %app.jointAngles(5) = app.savedAngles3(5);
            %app.updateArm()
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint4ValueEditField.Value = app.jointAngles(4);
            app.updateArm()
            
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(5) = changingValue;
            %TODO: also update the numerica value text field.
            app.Joint5ValueEditField.Value = app.jointAngles(5);
            app.updateArm()
            
        end

        % Button pushed function: Set4Button
        function Set4ButtonPushed(app, event)
            app.savedAngles4(1) = app.Joint1Slider.Value;
            app.savedAngles4(2) = app.Joint2Slider.Value;
            app.savedAngles4(3) = app.Joint3Slider.Value;
            app.savedAngles4(4) = app.Joint4Slider.Value;
            app.savedAngles4(5) = app.Joint5Slider.Value;

            app.set4_1.Value = app.savedAngles4(1);
            app.set4_2.Value = app.savedAngles4(2);
            app.set4_3.Value = app.savedAngles4(3);
            app.set4_4.Value = app.savedAngles4(4);
            app.set4_5.Value = app.savedAngles4(5);
            app.updateArm()
        end

        % Button pushed function: Goto4Button
        function Goto4ButtonPushed(app, event)
            app.animation(app.savedAngles4)
            %app.jointAngles(1) = app.savedAngles4(1);
            %app.jointAngles(2) = app.savedAngles4(2);
            %app.jointAngles(3) = app.savedAngles4(3);
            %app.jointAngles(4) = app.savedAngles4(4);
            %app.jointAngles(5) = app.savedAngles4(5);
            %app.updateArm()
        end

        % Button pushed function: Set5Button
        function Set5ButtonPushed(app, event)
            app.savedAngles5(1) = app.Joint1Slider.Value;
            app.savedAngles5(2) = app.Joint2Slider.Value;
            app.savedAngles5(3) = app.Joint3Slider.Value;
            app.savedAngles5(4) = app.Joint4Slider.Value;
            app.savedAngles5(5) = app.Joint5Slider.Value;

            app.set5_1.Value = app.savedAngles5(1);
            app.set5_2.Value = app.savedAngles5(2);
            app.set5_3.Value = app.savedAngles5(3);
            app.set5_4.Value = app.savedAngles5(4);
            app.set5_5.Value = app.savedAngles5(5);
            app.updateArm()
        end

        % Button pushed function: Goto5Button
        function Goto5ButtonPushed(app, event)
            app.animation(app.savedAngles5)           
            %app.jointAngles(1) = app.savedAngles5(1);
            %app.jointAngles(2) = app.savedAngles5(2);
            %app.jointAngles(3) = app.savedAngles5(3);
            %app.jointAngles(4) = app.savedAngles5(4);
            %app.jointAngles(5) = app.savedAngles5(5);
            %app.updateArm()
        end

        % Button pushed function: SpecialMoveChopVegetableButton
        function SpecialMoveChopVegetableButtonPushed(app, event)
            upPose = [0 32.7 -35.5 0.25 0 0];
            downPose = [0 -3.3 0.49 -3.54 0 0];
            numChops = 6;
            baseSweep = linspace(-20, 20, numChops);

            for i = 1:numChops
                up = upPose; 
                up(1) = baseSweep(i);
                up(4) = upPose(4) - baseSweep(i);

                down = downPose;
                down(1) = baseSweep(i);
                down(4) = downPose(4) - baseSweep(i);

                app.animation(up);
                app.animation(down);
                app.animation(up);
            end


        end

        % Button pushed function: PlayAllAll5SetsButton
        function PlayAllAll5SetsButtonPushed(app, event)
            poses = [app.savedAngles1;
                    app.savedAngles2;
                    app.savedAngles3;
                    app.savedAngles4;
                    app.savedAngles5];
            for i = 1:size(poses,1)
                app.animation(poses(i,:))

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1ValueEditFieldLabel.Text = 'Joint1 Value';
            app.Joint1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint2ValueLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2ValueLabel.Text = 'Joint2 Value';
            app.Joint2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint3ValueLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3ValueLabel.Text = 'Joint3 Value';
            app.Joint3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.GOButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.Set1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.set1_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Goto2Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.set2_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.Goto3Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.set3_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint4Label = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint4Slider.Limits = [-90 90];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint5Slider.Limits = [-90 90];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint4ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint4ValueEditFieldLabel.Text = 'Joint4 Value';
            app.Joint4ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Joint5ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint5ValueEditFieldLabel.Text = 'Joint5 Value';
            app.Joint5ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set4Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set4Button.ButtonPushedFcn = createCallbackFcn(app, @Set4ButtonPushed, true);
            app.Set4Button.Text = 'Set 4';
            app.Goto4Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto4Button.ButtonPushedFcn = createCallbackFcn(app, @Goto4ButtonPushed, true);
            app.Goto4Button.Text = 'Go to 4';
            app.set4_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set4_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set4_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.Set5Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Set5Button.ButtonPushedFcn = createCallbackFcn(app, @Set5ButtonPushed, true);
            app.Set5Button.Text = 'Set 5';
            app.Goto5Button = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.Goto5Button.ButtonPushedFcn = createCallbackFcn(app, @Goto5ButtonPushed, true);
            app.Goto5Button.Text = 'Go to 5';
            app.set5_2 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set5_1 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set5_3 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_4 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set1_5 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_4 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set2_5 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set5_4 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set5_5 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set4_4 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set4_5 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_4 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.set3_5 = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.SpecialMoveChopVegetableButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.SpecialMoveChopVegetableButton.ButtonPushedFcn = createCallbackFcn(app, @SpecialMoveChopVegetableButtonPushed, true);
            app.SpecialMoveChopVegetableButton.Text = {'Special Move'; '(Chop Vegetable)'};
            app.PlayAllAll5SetsButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.PlayAllAll5SetsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAllAll5SetsButtonPushed, true);
            app.PlayAllAll5SetsButton.Text = {'Play All '; '(All 5 Sets)'};
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam2BYOB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end