classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        Sad                  matlab.ui.control.Image
        Happy                matlab.ui.control.Image
        Gold                 matlab.ui.control.Image
        LabelPercentsValue   matlab.ui.control.Label
        LabelSuccessValue    matlab.ui.control.Label
        LabelAttemptValue    matlab.ui.control.Label
        PrecentCorrectLabel  matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
    end

    
    properties (Access = private)
        LeprechaunPosition
        GoldPositions
        LeprechaunImageSources

        Attempts = 0
        Success = 0
    end
    
    methods (Access = private)
        
        function makeAGuess(app,leprechaunIndex)
            app.Attempts = app.Attempts + 1;

            
            app.Happy.Position = app.LeprechaunPosition(leprechaunIndex,:);
            goldIndex = randi([1,3]);
            app.Gold.Position = app.GoldPositions(goldIndex,:);
            if goldIndex == leprechaunIndex
                 app.Happy.ImageSource = app.LeprechaunImageSources(1);
                 app.Success = app.Success + 1;
            else
                 app.Sad.ImageSource = app.LeprechaunImageSources(2);

            end 

            app.LabelAttemptValue.Text = num2str(app.Attempts);
            app.LabelSuccessValue.Text = num2str(app.Success);
            app.LabelPercentsValue.Text = num2str(app.Success / app.Attempts* 100);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprechaunImageSources = ["./Leprechaunhappy.png","./LeprechaunSad.png"];

            app.LeprechaunPosition(1,:) = [45,218,160,100];
            app.LeprechaunPosition(2,:) = [254,218,160,100];
            app.LeprechaunPosition(3,:) = [445,218,160,100];

            app.GoldPositions(1,:) = [76,17,129,95];
            app.GoldPositions(2,:) = [269,17,129,95];
            app.GoldPositions(3,:) = [460,17,129,95];
          

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           % app.Happy.ImageSource = app.LeprechaunImageSources(2);
           % app.Happy.Position = app.LeprechaunPosition(3,:);
            app.makeAGuess(3)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           % app.Happy.ImageSource = app.LeprechaunImageSources(1);
           % app.Happy.Position = app.LeprechaunPosition(2,:);
            app.makeAGuess(2)

        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
           % app.Happy.ImageSource = app.LeprechaunImageSources(1);
           % app.Happy.Position = app.LeprechaunPosition(1,:);
            app.makeAGuess(1)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'Gold Guesser';
            app.Button_1 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AttemptsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.SuccessLabel = uilabel(app.GoldGuesserUIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.PrecentCorrectLabel = uilabel(app.GoldGuesserUIFigure);
            app.PrecentCorrectLabel.Text = 'Precent Correct:';
            app.LabelAttemptValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelAttemptValue.Text = '0';
            app.LabelSuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelSuccessValue.Text = '0';
            app.LabelPercentsValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelPercentsValue.Text = '%';
            app.Gold = uiimage(app.GoldGuesserUIFigure);
            app.Gold.ImageSource = fullfile(pathToMLAPP, 'leprechuangold.png');
            app.Happy = uiimage(app.GoldGuesserUIFigure);
            app.Happy.ImageSource = fullfile(pathToMLAPP, 'Leprechaunhappy.png');
            app.Sad = uiimage(app.GoldGuesserUIFigure);
            app.Sad.ImageSource = fullfile(pathToMLAPP, 'LeprechaunSad.png');
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end