classdef LedOnOff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LedOnOffUIFigure  matlab.ui.Figure
        LEDOffButton      matlab.ui.control.Button
        LEDOnButton       matlab.ui.control.Button
        LEDONOFFLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObj 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.SerialObj = serialport("COM5",19200,"Timeout",15);

        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            fprintf("You pressed the ON button. \n");

            flush(app.SerialObj)
            writeline(app.SerialObj,"LED ON");
            response = readline(app.SerialObj)
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            fprintf("You pressed the OFF button. \n");
            flush(app.SerialObj)
            writeline(app.SerialObj,"LED OFF");
            response = readline(app.SerialObj)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LedOnOffUIFigure = uifigure('Visible', 'off');
            app.LedOnOffUIFigure.Name = 'LedOn/Off';
            app.LEDONOFFLabel = uilabel(app.LedOnOffUIFigure);
            app.LEDONOFFLabel.Text = 'LED ON / OFF';
            app.LEDOnButton = uibutton(app.LedOnOffUIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.LedOnOffUIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            % Show the figure after all components are created
            app.LedOnOffUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LedOnOff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LedOnOffUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LedOnOffUIFigure)
        end
    end
end