classdef TakeHomeExam1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EXAM1Label                    matlab.ui.control.Label
        period                        matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel        matlab.ui.control.Label
        nums                          matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel      matlab.ui.control.Label
        ServerResponse                matlab.ui.control.EditField
        ServerResponseEditFieldLabel  matlab.ui.control.Label
        FLASHButton                   matlab.ui.control.Button
        LEDOFFButton                  matlab.ui.control.Button
        LEDONButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        baseUrl

    end
    
    methods (Access = private)
        
        function send_command(app,command)
            url = app.baseUrl + command;
            reply = webread(url);
            app.ServerResponse.Value = reply;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.baseUrl = "http://137.112.217.96:8080/api/";
        end

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            send_command(app, "led/on");
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            send_command(app, "led/off");
        end

        % Button pushed function: FLASHButton
        function FLASHButtonPushed(app, event)
            periodMs = app.period.Value;
            numflashes = app.nums.Value;
            command = sprintf("flash/%d/%d",numflashes,periodMs);
            send_command(app, command)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDONButton = uibutton(app.UIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.UIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.FLASHButton = uibutton(app.UIFigure, 'push');
            app.FLASHButton.ButtonPushedFcn = createCallbackFcn(app, @FLASHButtonPushed, true);
            app.FLASHButton.Text = 'FLASH';
            app.ServerResponseEditFieldLabel = uilabel(app.UIFigure);
            app.ServerResponseEditFieldLabel.Text = 'Server Response';
            app.ServerResponse = uieditfield(app.UIFigure, 'text');
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.nums = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period(ms)';
            app.period = uieditfield(app.UIFigure, 'numeric');
            app.EXAM1Label = uilabel(app.UIFigure);
            app.EXAM1Label.Text = 'EXAM 1 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TakeHomeExam1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end