classdef Exam3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3UIFigure       matlab.ui.Figure
        cmLabel             matlab.ui.control.Label
        DistanceLabel       matlab.ui.control.Label
        BothReverseButton   matlab.ui.control.Button
        BothForwardButton   matlab.ui.control.Button
        RightReverseButton  matlab.ui.control.Button
        RightForwardButton  matlab.ui.control.Button
        LeftReverseButton   matlab.ui.control.Button
        LeftForwardButton   matlab.ui.control.Button
        LeftSlider          matlab.ui.control.Slider
        LeftSliderLabel     matlab.ui.control.Label
        RightSlider         matlab.ui.control.Slider
        RightSliderLabel    matlab.ui.control.Label
        TimeSlider          matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
    end


    properties (Access = private)
        mqClient


    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
             fprintf("Recieved: %s\n", message);
             messageStruct = jsondecode(message);
             messageType = messageStruct.type;
    

             if strcmp(messageType, "ultra")
                 distance = messageStruct.payload;
                 app.cmLabel.Text = sprintf(" %d cm",round(distance));
             end

        end

        function sendMessage(app, messageType, messagePayload)
                messageStruct.type = messageType;

                if nargin == 3
                    messageStruct.payload = messagePayload;
                end
                messageJson = jsonencode(messageStruct);
                write(app.mqClient, "ME435/jason/to_pi", messageJson);

                fprintf("Sent: %s\n", messageJson);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("ME435/jason/to_computer", "Callback", @app.myCallback)
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            direction = 0;
            t = app.TimeSlider.Value;
            r_pwm = app.RightSlider.Value;
            l_pwm = app.LeftSlider.Value;
            app.sendMessage("both",[direction,t, l_pwm, r_pwm] )
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            direction = 1;
            t = app.TimeSlider.Value;
            r_pwm = app.RightSlider.Value;
            l_pwm = app.LeftSlider.Value;
            app.sendMessage("both",[direction,t, l_pwm, r_pwm] )
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            direction = 0;
            t = app.TimeSlider.Value;
            pwm = app.RightSlider.Value;
            app.sendMessage("right",[direction,t, pwm] )
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            direction = 1;
            t = app.TimeSlider.Value;
            pwm = app.RightSlider.Value;
            app.sendMessage("right",[direction,t, pwm] )
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            direction = 0;
            t = app.TimeSlider.Value;
            pwm = app.LeftSlider.Value;
            app.sendMessage("left",[direction,t, pwm] )   
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            direction = 1;
            t = app.TimeSlider.Value;
            pwm = app.LeftSlider.Value;
            app.sendMessage("left",[direction,t, pwm] )

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3UIFigure = uifigure('Visible', 'off');
            app.Exam3UIFigure.Name = 'Exam 3 MQTT';
            app.TimeSliderLabel = uilabel(app.Exam3UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Exam3UIFigure);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.RightSliderLabel = uilabel(app.Exam3UIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Exam3UIFigure);
            app.RightSlider.Value = 40;
            app.LeftSliderLabel = uilabel(app.Exam3UIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Exam3UIFigure);
            app.LeftSlider.StepMode = 'manual';
            app.LeftSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.Exam3UIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftReverseButton = uibutton(app.Exam3UIFigure, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.RightForwardButton = uibutton(app.Exam3UIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.RightReverseButton = uibutton(app.Exam3UIFigure, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.BothForwardButton = uibutton(app.Exam3UIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothReverseButton = uibutton(app.Exam3UIFigure, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            app.DistanceLabel = uilabel(app.Exam3UIFigure);
            app.DistanceLabel.Text = 'Distance = ';
            app.cmLabel = uilabel(app.Exam3UIFigure);
            app.cmLabel.Text = '0 cm';
            % Show the figure after all components are created
            app.Exam3UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3UIFigure)
        end
    end
end