classdef Lab6_Car_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StreamingPanel             matlab.ui.container.Panel
        TimeTimestampLabel         matlab.ui.control.Label
        StreamingSwitch            matlab.ui.control.Switch
        StreamingSwitchLabel       matlab.ui.control.Label
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_3Label                matlab.ui.control.Label
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_2Label                matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        ModesPanel                 matlab.ui.container.Panel
        StartLineFollowingButton   matlab.ui.control.Button
        StartLightFollowingButton  matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        DriveuntillineButton       matlab.ui.control.Button
        DriveuntilwallButton       matlab.ui.control.Button
        BuzzerButton               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        SpeedSlider                matlab.ui.control.Slider
        SpeedSliderLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = ''

    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
             fprintf("Recieved: %s\n", message);
             messageStruct = jsondecode(message);
             messageType = messageStruct.type;
    

             if ~isempty(messageStruct.payload)
                 messagePayload = messageStruct.payload;
             end

             %if isfield(messageStruct, "payload") && ~isempty(messageStruct.payload)
             %end

             if strcmp (messageType, "line_sensor_reading")
                 messagePayload = messageStruct.payload;
                 app.TimeTimesstampLabel.Text = messagePayload.timestamp;
                 app.LeftLamp.Color = messagePayload.left;
                 app.MiddleLamp.Color = messagePayload.middle;
                 app.RightLamp.Color = messagePayload.right;
             end


        end

        function sendMessage(app, messageType, messagePayload)
                messageStruct.type = messageType;

                if nargin >2
                    messageStruct.payload = messagePayload;
                end

                %if strcmp(messageType, "set_speeds")
                %    messageStruct.payload = messagePayload;
                %end
                messageJson = jsonencode(messageStruct);
                write(app.mqClient, "ME435/otto/to_pi", messageJson);

                fprintf("Sent: %s\n", messageJson);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("ME435/#", "Callback", @app.myCallback)
        end

        % Callback function
        function UIFigureWindowKeyPress(app, event)
 
        end

        % Callback function
        function UIFigureWindowKeyRelease(app, event)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
           key = event.Key;
            if strcmp(key, app.priorKey)
                return
            end

            app.priorKey = key;
            speed = round(app.SpeedSlider.Value);
            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds",[speed,speed,speed,speed] )
            end
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds",[-speed,-speed,-speed,-speed] )
            end
            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds",[-speed,speed,speed,-speed] )
            end
            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds",[speed,-speed,-speed,speed] )
            end
            if strcmp(key, "a")
                app.sendMessage("set_speeds",[-speed,-speed,speed,speed] )
            end
            if strcmp(key, "d")
                app.sendMessage("set_speeds",[speed,speed,-speed,-speed] )
            end

            
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")
            
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            app.sendMessage("beep",[0.1,0.3,2])
        end

        % Button pushed function: DriveuntilwallButton
        function DriveuntilwallButtonPushed(app, event)
            app.sendMessage("mode","drive_until_wall")
        end

        % Button pushed function: DriveuntillineButton
        function DriveuntillineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line")
        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            value = app.StreamingSwitch.Value;
            if strcmp (value, "On")
                app.sendMessage("is_streaming", true);
            else
                app.sendMessage("is_streaming", false);
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode","none")
        end

        % Button pushed function: StartLineFollowingButton
        function StartLineFollowingButtonPushed(app, event)
            app.sendMessage("mode", "line_following")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use in the keyboard';
            app.BuzzerButton = uibutton(app.UIFigure, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveuntilwallButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilwallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilwallButtonPushed, true);
            app.DriveuntilwallButton.Text = 'Drive until wall';
            app.DriveuntillineButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntillineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntillineButtonPushed, true);
            app.DriveuntillineButton.Text = 'Drive until line';
            app.StopButton = uibutton(app.ModesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartLightFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLightFollowingButton.Text = 'Start Light Following';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLineFollowingButtonPushed, true);
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StreamingPanel = uipanel(app.UIFigure);
            app.StreamingPanel.Title = 'Streaming';
            app.LampLabel = uilabel(app.StreamingPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.StreamingPanel);
            app.Lamp_2Label = uilabel(app.StreamingPanel);
            app.Lamp_2Label.Text = 'Lamp';
            app.Lamp_2 = uilamp(app.StreamingPanel);
            app.Lamp_3Label = uilabel(app.StreamingPanel);
            app.Lamp_3Label.Text = 'Lamp';
            app.Lamp_3 = uilamp(app.StreamingPanel);
            app.StreamingSwitchLabel = uilabel(app.StreamingPanel);
            app.StreamingSwitchLabel.Text = 'Streaming';
            app.StreamingSwitch = uiswitch(app.StreamingPanel, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.TimeTimestampLabel = uilabel(app.StreamingPanel);
            app.TimeTimestampLabel.Text = 'Time Timestamp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6_Car_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end